classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimlasyonUIFigure         matlab.ui.Figure
        harita                    matlab.ui.control.UIAxes
        sinyaller                 matlab.ui.control.UIAxes
        s11                       matlab.ui.control.UIAxes
        s21                       matlab.ui.control.UIAxes
        s31                       matlab.ui.control.UIAxes
        s41                       matlab.ui.control.UIAxes
        s51                       matlab.ui.control.UIAxes
        GirdilerPanel             matlab.ui.container.Panel
        KonumlarButton            matlab.ui.control.Button
        FrekansHzLabel            matlab.ui.control.Label
        FrekansHzEditField        matlab.ui.control.NumericEditField
        SesHzmsLabel              matlab.ui.control.Label
        SesHiziEditField          matlab.ui.control.NumericEditField
        rneklemeHzLabel           matlab.ui.control.Label
        OrneklemeHiziEditField    matlab.ui.control.NumericEditField
        BantGeniliiHzLabel        matlab.ui.control.Label
        BantGenislgiEditField     matlab.ui.control.NumericEditField
        SNRdBLabel                matlab.ui.control.Label
        SNRdBEditField            matlab.ui.control.NumericEditField
        SpektrogramButton         matlab.ui.control.Button
        SredkLabel                matlab.ui.control.Label
        SuredkEditField           matlab.ui.control.NumericEditField
        BaslatButton              matlab.ui.control.Button
        ZayflamaymodelleCheckBox  matlab.ui.control.CheckBox
        DaaktarButton             matlab.ui.control.Button
        PencereuzunluuLabel       matlab.ui.control.Label
        PencereuzunluguEditField  matlab.ui.control.NumericEditField
        OverlapLabel              matlab.ui.control.Label
        OverlapEditField          matlab.ui.control.NumericEditField
        AyrlmcheckboxCheckBox     matlab.ui.control.CheckBox
        FazFarklarButton          matlab.ui.control.Button
        FazFarklarButton_2        matlab.ui.control.Button
        FazFarklarButton_3        matlab.ui.control.Button
        FazFarklarButton_4        matlab.ui.control.Button
        FazFarklarButton_5        matlab.ui.control.Button
        Image                     matlab.ui.control.Image
        s12                       matlab.ui.control.UIAxes
        s22                       matlab.ui.control.UIAxes
        s32                       matlab.ui.control.UIAxes
        s42                       matlab.ui.control.UIAxes
        s52                       matlab.ui.control.UIAxes
        Slider                    matlab.ui.control.Slider
        Panel                     matlab.ui.container.Panel
        PenceretrDropDownLabel    matlab.ui.control.Label
        PenceretrDropDown         matlab.ui.control.DropDown
        Label                     matlab.ui.control.Label
        KaydetmeYeriButton        matlab.ui.control.Button
        GrltmodeliDropDownLabel   matlab.ui.control.Label
        GrltmodeliDropDown        matlab.ui.control.DropDown
        GurultuCizButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        % Kullanıcı bağımlı değişkenler
        freq=50;
        v=2000;
        bandwidth=1;
        snr=1e6;
        wlen=8192;
        overlap=1024;
        tlast=1;
        f=[];
        tur='Hanning';
        zayiflat=false;
        noisetur='Rastgele';
        % İç değişkenler
        liner_sub;
        liner_top;
        path;
    end
    
    properties (Access = public)
        sinyal1=[];sinyal2=[];sinyal3=[];sinyal4=[];sinyal5=[];
        sg1c=[];sg2c=[];sg3c=[];sg4c=[];sg5c=[];
        sf1c=[];sf2c=[];sf3c=[];sf4c=[];sf5c=[];
        s1pos=[nan,nan];
        s2pos=[nan,nan];
        s3pos=[nan,nan];
        s4pos=[nan,nan];
        s5pos=[nan,nan];
        kpos=[nan,nan];
        state;
        time;
        Fs=200;
        flag;
        % Alt figür
        fspektrogram=0;
    end
    
    methods (Access = public)
        function updatekonum(app,subapp)
            app.s1pos=subapp.s1pos;
            app.s2pos=subapp.s2pos;
            app.s3pos=subapp.s3pos;
            app.s4pos=subapp.s4pos;
            app.s5pos=subapp.s5pos;
            app.kpos=subapp.kpos;
            app.state=subapp.state;
            plotharita(app,0);
            idx=strfind(app.path,'\');
            idx=idx(end-1);
            app.path=[app.path(1:idx) '(' num2str(app.kpos(1)) ',' num2str(app.kpos(2)) ')\'];
            app.Label.Text=app.path;
        end
        
        function updatespekt(app,subapp)
            app.fspektrogram=struct;
            app.fspektrogram.tur=subapp.tur;
            app.fspektrogram.harita=subapp.harita;
            app.fspektrogram.wlen=subapp.wlen;
            app.fspektrogram.overlap=subapp.overlap;
        end
    end
    
    methods (Access = private)
        
        function plotharita(app,flag)
            hold(app.harita,'off');
            if flag
                % Olası hedefleri çizdirme yeri
            end
            scatter(app.harita,app.s1pos(1),app.s1pos(2),'b');
            hold(app.harita,'on');
            text(app.harita,app.s1pos(1),app.s1pos(2),'S1');
            scatter(app.harita,app.s2pos(1),app.s2pos(2),'b');
            text(app.harita,app.s2pos(1),app.s2pos(2),'S2');
            scatter(app.harita,app.s3pos(1),app.s3pos(2),'b');
            text(app.harita,app.s3pos(1),app.s3pos(2),'S3');
            scatter(app.harita,app.s4pos(1),app.s4pos(2),'b');
            text(app.harita,app.s4pos(1),app.s4pos(2),'S4');
            scatter(app.harita,app.s5pos(1),app.s5pos(2),'b');
            text(app.harita,app.s5pos(1),app.s5pos(2),'S5');
            scatter(app.harita,app.kpos(1),app.kpos(2),'g*');
            text(app.harita,app.kpos(1),app.kpos(2),'K');
            legend(app.harita,'S1','S2','S3','S4','S5','K','Location','best');
        end
        
        
        function fazfarki(app,nbutton)
            if app.flag==2
                uialert(app.SimlasyonUIFigure,'Sinyal üretildikten sonra bazı değişkenler değiştirildi. Lütfen simülasyonu tekrarlayın.','Dikkat');
                return; 
            end
            switch nbutton
                case 1
                    if sum([size(app.sg1c,1) size(app.sf1c,1) size(app.sg2c,1) size(app.sf2c,1) size(app.sg3c,1) size(app.sf3c,1) size(app.sg4c,1) size(app.sf4c,1) size(app.sg5c,1) size(app.sf5c,1)])==10
                        sgmain=app.sg1c;sfmain=app.sf1c;
                        sgside1=app.sg2c;sfside1=app.sf2c;
                        sgside2=app.sg3c;sfside2=app.sf3c;
                        sgside3=app.sg4c;sfside3=app.sf4c;
                        sgside4=app.sg5c;sfside4=app.sf5c;
                    else
                        sgmain=mean(app.sg1c);sfmain=mean(app.sf1c);
                        sgside1=mean(app.sg2c);sfside1=mean(app.sf2c);
                        sgside2=mean(app.sg3c);sfside2=mean(app.sf3c);
                        sgside3=mean(app.sg4c);sfside3=mean(app.sf4c);
                        sgside4=mean(app.sg5c);sfside4=mean(app.sf5c);
                    end
                    labels=['S1';'S2';'S3';'S4';'S5'];
                case 2
                    if sum([size(app.sg1c,1) size(app.sf1c,1) size(app.sg2c,1) size(app.sf2c,1) size(app.sg3c,1) size(app.sf3c,1) size(app.sg4c,1) size(app.sf4c,1) size(app.sg5c,1) size(app.sf5c,1)])==10
                        sgmain=app.sg2c;sfmain=app.sf2c;
                        sgside1=app.sg1c;sfside1=app.sf1c;
                        sgside2=app.sg3c;sfside2=app.sf3c;
                        sgside3=app.sg4c;sfside3=app.sf4c;
                        sgside4=app.sg5c;sfside4=app.sf5c;
                    else
                        sgmain=mean(app.sg2c);sfmain=mean(app.sf2c);
                        sgside1=mean(app.sg1c);sfside1=mean(app.sf1c);
                        sgside2=mean(app.sg3c);sfside2=mean(app.sf3c);
                        sgside3=mean(app.sg4c);sfside3=mean(app.sf4c);
                        sgside4=mean(app.sg5c);sfside4=mean(app.sf5c);
                    end
                    labels=['S2';'S1';'S3';'S4';'S5'];
                case 3
                    if sum([size(app.sg1c,1) size(app.sf1c,1) size(app.sg2c,1) size(app.sf2c,1) size(app.sg3c,1) size(app.sf3c,1) size(app.sg4c,1) size(app.sf4c,1) size(app.sg5c,1) size(app.sf5c,1)])==10
                        sgmain=app.sg3c;sfmain=app.sf3c;
                        sgside1=app.sg1c;sfside1=app.sf1c;
                        sgside2=app.sg2c;sfside2=app.sf2c;
                        sgside3=app.sg4c;sfside3=app.sf4c;
                        sgside4=app.sg5c;sfside4=app.sf5c;
                    else
                        sgmain=mean(app.sg3c);sfmain=mean(app.sf3c);
                        sgside1=mean(app.sg1c);sfside1=mean(app.sf1c);
                        sgside2=mean(app.sg2c);sfside2=mean(app.sf2c);
                        sgside3=mean(app.sg4c);sfside3=mean(app.sf4c);
                        sgside4=mean(app.sg5c);sfside4=mean(app.sf5c);
                    end
                    labels=['S3';'S1';'S2';'S4';'S5'];
                case 4
                    if sum([size(app.sg1c,1) size(app.sf1c,1) size(app.sg2c,1) size(app.sf2c,1) size(app.sg3c,1) size(app.sf3c,1) size(app.sg4c,1) size(app.sf4c,1) size(app.sg5c,1) size(app.sf5c,1)])==10
                        sgmain=app.sg4c;sfmain=app.sf4c;
                        sgside1=app.sg1c;sfside1=app.sf1c;
                        sgside2=app.sg2c;sfside2=app.sf2c;
                        sgside3=app.sg3c;sfside3=app.sf3c;
                        sgside4=app.sg5c;sfside4=app.sf5c;
                    else
                        sgmain=mean(app.sg4c);sfmain=mean(app.sf4c);
                        sgside1=mean(app.sg1c);sfside1=mean(app.sf1c);
                        sgside2=mean(app.sg2c);sfside2=mean(app.sf2c);
                        sgside3=mean(app.sg3c);sfside3=mean(app.sf3c);
                        sgside4=mean(app.sg5c);sfside4=mean(app.sf5c);
                    end
                    labels=['S4';'S1';'S2';'S3';'S5'];
                case 5
                    if sum([size(app.sg1c,1) size(app.sf1c,1) size(app.sg2c,1) size(app.sf2c,1) size(app.sg3c,1) size(app.sf3c,1) size(app.sg4c,1) size(app.sf4c,1) size(app.sg5c,1) size(app.sf5c,1)])==10
                        sgmain=app.sg5c;sfmain=app.sf5c;
                        sgside1=app.sg1c;sfside1=app.sf1c;
                        sgside2=app.sg2c;sfside2=app.sf2c;
                        sgside3=app.sg3c;sfside3=app.sf3c;
                        sgside4=app.sg4c;sfside4=app.sf4c;
                    else
                        sgmain=mean(app.sg5c);sfmain=mean(app.sf5c);
                        sgside1=mean(app.sg1c);sfside1=mean(app.sf1c);
                        sgside2=mean(app.sg2c);sfside2=mean(app.sf2c);
                        sgside3=mean(app.sg3c);sfside3=mean(app.sf3c);
                        sgside4=mean(app.sg4c);sfside4=mean(app.sf4c);
                    end
                    labels=['S5';'S1';'S2';'S3';'S4'];
            end
            figure;
            nax=subplot(4,2,1);
            plot(nax,app.f,[sgmain;sgside1]');
            title(nax,[labels(1,:) '-' labels(2,:)]);ylabel(nax,'|Y^2|')
            
            nax=subplot(4,2,3);
            plot(nax,app.f,[sfmain;sfside1;sfmain-sfside1]');
            ylabel(nax,'\theta');legend(nax,labels(1,:),labels(2,:),'Fark');
            
            nax=subplot(4,2,2);
            plot(nax,app.f,[sgmain;sgside2]');
            title(nax,[labels(1,:) '-' labels(3,:)]);ylabel(nax,'|Y^2|')
            nax=subplot(4,2,4);
            plot(nax,app.f,[sfmain;sfside2;sfmain-sfside2]');
            ylabel(nax,'\theta');legend(nax,labels(1,:),labels(3,:),'Fark');
            
            nax=subplot(4,2,5);
            plot(nax,app.f,[sgmain;sgside3]');
            title(nax,[labels(1,:) '-' labels(4,:)]);ylabel(nax,'|Y^2|')
            nax=subplot(4,2,7);
            plot(nax,app.f,[sfmain;sfside3;sfmain-sfside3]');
            ylabel(nax,'\theta');legend(nax,labels(1,:),labels(4,:),'Fark');
            
            nax=subplot(4,2,6);
            plot(nax,app.f,[sgmain;sgside4]');
            title(nax,[labels(1,:) '-' labels(5,:)]);ylabel(nax,'|Y^2|')
            nax=subplot(4,2,8);
            plot(nax,app.f,[sfmain;sfside4;sfmain-sfside4]');
            ylabel(nax,'\theta');legend(nax,labels(1,:),labels(5,:),'Fark');
        end
        
        function plotoutspekt(~,e,n,z,number)
            figure;
            nax=subplot(3,1,1);
            spectrogram(e,hann(8192),1024,'yaxis');
            colormap(nax,'bone');title(nax,['S' num2str(number)]);ylabel(nax,'E');
            nax=subplot(3,1,2);
            spectrogram(n,hann(8192),1024,'yaxis');
            colormap(nax,'bone');ylabel(nax,'N');
            nax=subplot(3,1,3);
            spectrogram(z,hann(8192),1024,'yaxis');
            colormap(nax,'bone');ylabel(nax,'Z');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.path=[pwd '\(0,0)\'];
            app.Label.Text=app.path;
            
            app.s11.Toolbar.Visible='off';app.s12.Toolbar.Visible='off';
            app.s21.Toolbar.Visible='off';app.s22.Toolbar.Visible='off';
            app.s31.Toolbar.Visible='off';app.s32.Toolbar.Visible='off';
            app.s41.Toolbar.Visible='off';app.s42.Toolbar.Visible='off';
            app.s51.Toolbar.Visible='off';app.s52.Toolbar.Visible='off';
            
            axis(app.sinyaller,'off');
            axis(app.s11,'off');axis(app.s12,'off');
            axis(app.s21,'off');axis(app.s22,'off');
            axis(app.s31,'off');axis(app.s32,'off');
            axis(app.s41,'off');axis(app.s42,'off');
            axis(app.s51,'off');axis(app.s52,'off');
        end

        % Button pushed function: KonumlarButton
        function KonumlarButtonPushed(app, event)
            app2(app);
            app.flag=0;
        end

        % Button pushed function: BaslatButton
        function BaslatButtonPushed(app, event)
            if sum(isnan([app.s1pos app.s2pos app.s3pos app.s4pos app.s5pos]))>0
                uialert(app.SimlasyonUIFigure,'Lütfen sensörlere ve/veya kaynağa ait konumları girin','Geçersiz konum');
                return;
            end
            % Axis çizgilerini aç
            axis(app.sinyaller,'on');
            axis(app.s11,'on');axis(app.s12,'on');
            axis(app.s21,'on');axis(app.s22,'on');
            axis(app.s31,'on');axis(app.s32,'on');
            axis(app.s41,'on');axis(app.s42,'on');
            axis(app.s51,'on');axis(app.s52,'on');
            % Progress bar ekle
            bar=uiprogressdlg(app.SimlasyonUIFigure,'Title','Lütfen Bekleyin','Message','Sinyal oluşturuluyor...');
            bar.Value=0.05;
            % Kullanıcı Bağımsız Değişkenler
            adder=app.tlast/2*60;
            t=[0:1/app.Fs:app.tlast*60];
            app.sg1c=[];app.sg2c=[];app.sg3c=[];app.sg4c=[];app.sg5c=[];
            app.sf1c=[];app.sf2c=[];app.sf3c=[];app.sf4c=[];app.sf5c=[];
            basesignal=[zeros(1,15) 1 zeros(1,15)];
            range=[1,app.wlen+1];
            
            % Timeshift
            dk1=sqrt(sum((app.kpos-app.s1pos).^2));t1shift=dk1/app.v;
            dk2=sqrt(sum((app.kpos-app.s2pos).^2));t2shift=dk2/app.v;
            dk3=sqrt(sum((app.kpos-app.s3pos).^2));t3shift=dk3/app.v;
            dk4=sqrt(sum((app.kpos-app.s4pos).^2));t4shift=dk4/app.v;
            dk5=sqrt(sum((app.kpos-app.s5pos).^2));t5shift=dk5/app.v;
            
            s1c=sin(2*pi*app.freq*t);
            s2c=sin(2*pi*app.freq*t);
            s3c=sin(2*pi*app.freq*t);
            s4c=sin(2*pi*app.freq*t);
            s5c=sin(2*pi*app.freq*t);
            
            bar.Value=0.1;
            shifter1=shiftsignal(basesignal,10000,t1shift*app.Fs);
            shifter2=shiftsignal(basesignal,10000,t2shift*app.Fs);
            shifter3=shiftsignal(basesignal,10000,t3shift*app.Fs);
            bar.Value=0.15;
            shifter4=shiftsignal(basesignal,10000,t4shift*app.Fs);
            shifter5=shiftsignal(basesignal,10000,t5shift*app.Fs);
            
            bar.Value=0.2;
            s1c=conv(s1c,shifter1);
            s2c=conv(s2c,shifter2);
            bar.Value=0.25;
            s3c=conv(s3c,shifter3);
            s4c=conv(s4c,shifter4);
            bar.Value=0.3;
            s5c=conv(s5c,shifter5);
            
            % Padding
            bar.Value=0.35;
            lengths=[length(s1c) length(s2c) length(s3c) length(s4c) length(s5c)];
            max_length=max(lengths);
            if length(s1c)<max_length
                s1c=[s1c zeros(1,max_length-length(s1c))];
            end
            if length(s2c)<max_length
                s2c=[s2c zeros(1,max_length-length(s2c))];
            end
            if length(s3c)<max_length
                s3c=[s3c zeros(1,max_length-length(s3c))];
            end
            if length(s4c)<max_length
                s4c=[s4c zeros(1,max_length-length(s4c))];
            end
            if length(s5c)<max_length
                s5c=[s5c zeros(1,max_length-length(s5c))];
            end
                        
            s1c=[zeros(1,app.Fs*adder) s1c zeros(1,app.Fs*adder)];
            s2c=[zeros(1,app.Fs*adder) s2c zeros(1,app.Fs*adder)];
            s3c=[zeros(1,app.Fs*adder) s3c zeros(1,app.Fs*adder)];
            s4c=[zeros(1,app.Fs*adder) s4c zeros(1,app.Fs*adder)];
            s5c=[zeros(1,app.Fs*adder) s5c zeros(1,app.Fs*adder)];
            
            % Noise
            if app.zayiflat
                w=2*pi*app.freq;
                er=16;
                sigma=1e-2;
                alpha=sqrt(w*4*pi*1e-7/2)*sqrt(-w*er*8.854*1e-12+sqrt((w*8.854*1e-12*er).^2+sigma^2));
                s1c=s1c*exp(-alpha*dk1);
                s2c=s2c*exp(-alpha*dk2);
                s3c=s3c*exp(-alpha*dk3);
                s4c=s4c*exp(-alpha*dk4);
                s5c=s5c*exp(-alpha*dk5);
            end
            
            s1c=awgn(s1c,app.snr);
            s2c=awgn(s2c,app.snr);
            s3c=awgn(s3c,app.snr);
            s4c=awgn(s4c,app.snr);
            s5c=awgn(s5c,app.snr);
            
            bar.Value=0.45;
            app.sinyal1=s1c;
            app.sinyal2=s2c;
            app.sinyal3=s3c;
            app.sinyal4=s4c;
            app.sinyal5=s5c;
            
            % Plot signals
            app.time=seconds(0:1/app.Fs:(length(s1c)-1)/app.Fs);
            hold(app.sinyaller,'off');
            plot(app.sinyaller,app.time,s1c+2);
            hold(app.sinyaller,'on');
            plot(app.sinyaller,app.time,s2c+5);
            plot(app.sinyaller,app.time,s3c+8);
            plot(app.sinyaller,app.time,s4c+11);
            plot(app.sinyaller,app.time,s5c+14);
            %1/86401=1 saniye => 1/Fs/86401= 1 nokta
            app.liner_sub=plot(app.sinyaller,[seconds(0) seconds(0)],app.sinyaller.YLim,'--r');
            app.liner_top=plot(app.sinyaller,[seconds(0)+(app.wlen-1)/app.Fs/86401 seconds(0)+(app.wlen-1)/app.Fs/86401],app.sinyaller.YLim,'--r');
            legend(app.sinyaller,'S1','S2','S3','S4','S5');
            legend(app.sinyaller,'boxoff');
            
            bar.Value=0.5;
            bar.Message='Sinyal inceleniyor...';
            pause(2);
            
            % Window type
            switch app.tur
                case 'Hanning'
                    window=hann(app.wlen);
                case 'Hamming'
                    window=hamming(app.wlen);
                case 'Rectangle'
                    window=rectwin(app.wlen);
                case 'Bartlett'
                    window=bartlett(app.wlen);
                case 'Blackman'
                    window=blackman(app.wlen);
                case 'Chebyshev'
                    window=chebwin(app.wlen);
                case 'Kaiser'
                    window=kaiser(app.wlen);
                case 'Taylor'
                    window=taylorwin(app.wlen);
                case 'Triangular'
                    window=triang(app.wlen);
            end
            % Moving window
            while true
                [g1c,f1c,app.f]=getfft(s1c,window,app.wlen,app.freq,app.Fs,app.bandwidth,range);
                [g2c,f2c]=getfft(s2c,window,app.wlen,app.freq,app.Fs,app.bandwidth,range);
                [g3c,f3c]=getfft(s3c,window,app.wlen,app.freq,app.Fs,app.bandwidth,range);
                [g4c,f4c]=getfft(s4c,window,app.wlen,app.freq,app.Fs,app.bandwidth,range);
                [g5c,f5c]=getfft(s5c,window,app.wlen,app.freq,app.Fs,app.bandwidth,range);
                if (range(1)>adder*app.Fs && range(2)<(adder*app.Fs+length(t)))
                    app.sg1c=[app.sg1c;g1c'];app.sf1c=[app.sf1c;f1c'];
                    app.sg2c=[app.sg2c;g2c'];app.sf2c=[app.sf2c;f2c'];
                    app.sg3c=[app.sg3c;g3c'];app.sf3c=[app.sf3c;f3c'];
                    app.sg4c=[app.sg4c;g4c'];app.sf4c=[app.sf4c;f4c'];
                    app.sg5c=[app.sg5c;g5c'];app.sf5c=[app.sf5c;f5c'];
                end
                range=range+(app.wlen-app.overlap);
                if range(2)>length(s1c)
                    break;
                end
                app.liner_sub.XData=app.liner_sub.XData+(app.wlen-app.overlap)/app.Fs/86401;
                app.liner_top.XData=app.liner_top.XData+(app.wlen-app.overlap)/app.Fs/86401;
                pause(0.01);
            end
            app.flag=true;
            bar.Message='Tamamlandı.';
            bar.Value=1;
            
            % Post-process
            app.Slider.Enable=1;
            app.Slider.Limits=[0 length(s1c)/app.Fs-1];
            pause(1);
            close(bar);
        end

        % Value changed function: SesHiziEditField
        function SesHiziEditFieldValueChanged(app, event)
            app.v = app.SesHiziEditField.Value;
            app.flag=0;
        end

        % Value changed function: FrekansHzEditField
        function FrekansHzEditFieldValueChanged(app, event)
            app.freq = app.FrekansHzEditField.Value;
            app.flag=0;
        end

        % Value changed function: OrneklemeHiziEditField
        function OrneklemeHiziEditFieldValueChanged(app, event)
            app.Fs = app.OrneklemeHiziEditField.Value;
            app.flag=0;
        end

        % Value changed function: BantGenislgiEditField
        function BantGenislgiEditFieldValueChanged(app, event)
            app.bandwidth = app.BantGenislgiEditField.Value;
            app.flag=0;
        end

        % Value changed function: SNRdBEditField
        function SNRdBEditFieldValueChanged(app, event)
            app.snr = app.SNRdBEditField.Value;
            app.flag=0;
        end

        % Value changed function: PencereuzunluguEditField
        function PencereuzunluguEditFieldValueChanged(app, event)
            if app.PencereuzunluguEditField.Value<=app.overlap
                app.OverlapEditField.set('Value',app.PencereuzunluguEditField.Value/2);
                OverlapEditFieldValueChanged(app,event);
            end
            app.wlen = app.PencereuzunluguEditField.Value;
            app.SliderValueChanged(app);
            app.flag=2;
        end

        % Value changed function: OverlapEditField
        function OverlapEditFieldValueChanged(app, event)
            pause(0.5);
            if app.OverlapEditField.Value>=app.wlen
                app.OverlapEditField.set('Value',app.wlen/2);
                OverlapEditFieldValueChanged(app,event);
            end
            app.overlap = app.OverlapEditField.Value;
            app.SliderValueChanged(app);
            app.flag=2;
        end

        % Value changed function: SuredkEditField
        function SuredkEditFieldValueChanged(app, event)
            app.tlast = app.SuredkEditField.Value;
            app.flag=0;
        end

        % Button pushed function: SpektrogramButton
        function SpektrogramButtonPushed(app, event)
            if sum([isempty(app.sinyal1) isempty(app.sinyal2) isempty(app.sinyal3) isempty(app.sinyal4) isempty(app.sinyal5)])>0
                uialert(app.SimlasyonUIFigure,'Lütfen simülasyonu başlatarak verileri oluşturun','Sinyal bulunamadı');
                return;
            end
            if app.flag==0
                uialert(app.SimlasyonUIFigure,'Sinyal üretildikten sonra bazı değişkenler değiştirildi. Lütfen simülasyonu tekrarlayın.','Dikkat');
                return;
            end
            app3(app);
        end

        % Value changed function: ZayflamaymodelleCheckBox
        function ZayflamaymodelleCheckBoxValueChanged(app, event)
            app.zayiflat = app.ZayflamaymodelleCheckBox.Value;
            app.flag=0;
        end

        % Button pushed function: DaaktarButton
        function DaaktarButtonPushed(app, event)
            if sum([isempty(app.sinyal1) isempty(app.sinyal2) isempty(app.sinyal3) isempty(app.sinyal4) isempty(app.sinyal5)])>0
                uialert(app.SimlasyonUIFigure,'Lütfen simülasyonu başlatarak verileri oluşturun','Sinyal bulunamadı');
                return;
            end
            multiplier=1000;
            bar=uiprogressdlg(app.SimlasyonUIFigure,'Title','Lütfen Bekleyin','Message','Gürültü modeli yükleniyor...');
            bar.Value=0.05;
            % Loading noise figure data
            if strcmp(app.noisetur,'Rastgele')
                app.noisetur=app.GrltmodeliDropDown.Items{randi([2 length(app.GrltmodeliDropDown.Items)],1,1)};
                app.GrltmodeliDropDown.Value=app.noisetur;
                app.GurultuCizButton.Enable=1;
            end
            try
                load([app.noisetur '.mat'],'n1e','n1n','n1z');bar.Value=0.1;
                load([app.noisetur '.mat'],'n2e','n2n','n2z');bar.Value=0.15;
                load([app.noisetur '.mat'],'n3e','n3n','n3z');bar.Value=0.20;
                load([app.noisetur '.mat'],'n4e','n4n','n4z');bar.Value=0.25;
                load([app.noisetur '.mat'],'n5e','n5n','n5z');bar.Value=0.3;
            catch
                uialert(app.SimlasyonUIFigure,'Gürültü modeli yüklenemedi.','Hata');
                return
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Projection
            bar.Message='Sinyal oluşturuluyor...';
            s1e=app.sinyal1*0.61/1.39;
            s1n=app.sinyal1*0.3/1.39;
            s1z=app.sinyal1*0.48/1.39;
            
            s2e=app.sinyal2*0.46/1.8;
            s2n=app.sinyal2*0.33/1.8;
            s2z=app.sinyal2*1.01/1.8;
            
            s3e=app.sinyal3*0.88/3.42;
            s3n=app.sinyal3*1.4/3.42;
            s3z=app.sinyal3*1.14/3.42;
            
            s4e=app.sinyal4*0.29/1.14;
            s4n=app.sinyal4*0.46/1.14;
            s4z=app.sinyal4*0.39/1.14;
            
            s5e=app.sinyal5*0.8/2.92;
            s5n=app.sinyal5*0.67/2.92;
            s5z=app.sinyal5*1.453/2.92;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Combine with real noise
            bar.Value=0.5;
            first=randi([1 length(n1e)-length(s1e)],1,1);
            s1e=combineSN(s1e,n1e,multiplier,first);
            s1n=combineSN(s1n,n1n,multiplier,first);
            s1z=combineSN(s1z,n1z,multiplier,first);
            
            s2e=combineSN(s2e,n2e,multiplier,first);
            s2n=combineSN(s2n,n2n,multiplier,first);
            s2z=combineSN(s2z,n2z,multiplier,first);
            
            s3e=combineSN(s3e,n3e,multiplier,first);
            s3n=combineSN(s3n,n3n,multiplier,first);
            s3z=combineSN(s3z,n3z,multiplier,first);
            
            s4e=combineSN(s4e,n4e,multiplier,first);
            s4n=combineSN(s4n,n4n,multiplier,first);
            s4z=combineSN(s4z,n4z,multiplier,first);
            
            s5e=combineSN(s5e,n5e,multiplier,first);
            s5n=combineSN(s5n,n5n,multiplier,first);
            s5z=combineSN(s5z,n5z,multiplier,first);
            
            bar.Message='Dışa aktarılıyor...';
            mkdir(app.path);
            export2txt('s1e',s1e,app.path);bar.Value=0.53;export2txt('s1n',s1n,app.path);bar.Value=0.56;export2txt('s1z',s1z,app.path);bar.Value=0.6;
            export2txt('s2e',s2e,app.path);bar.Value=0.63;export2txt('s2n',s2n,app.path);bar.Value=0.66;export2txt('s2z',s2z,app.path);bar.Value=0.7;
            export2txt('s3e',s3e,app.path);bar.Value=0.73;export2txt('s3n',s3n,app.path);bar.Value=0.76;export2txt('s3z',s3z,app.path);bar.Value=0.8;
            export2txt('s4e',s4e,app.path);bar.Value=0.83;export2txt('s4n',s4n,app.path);bar.Value=0.86;export2txt('s4z',s4z,app.path);bar.Value=0.9;
            export2txt('s5e',s5e,app.path);bar.Value=0.93;export2txt('s5n',s5n,app.path);bar.Value=0.96;export2txt('s5z',s5z,app.path);bar.Value=1;
            bar.Message='Tamamlandı.';
            pause(1);
            bar.Message='Çıktı sinyal çizdiriliyor...';
            
            plotoutspekt(app,s1e,s1n,s1z,1);
            plotoutspekt(app,s2e,s2n,s2z,2);
            plotoutspekt(app,s3e,s3n,s3z,3);
            plotoutspekt(app,s4e,s4n,s4z,4);
            plotoutspekt(app,s5e,s5n,s5z,5);
            
            % Verileri kaydet
            fileID=fopen([app.path 'data.txt'],'w');
            fprintf(fileID,"----------Sensör konfigürasyonu----------\n\n");
            fprintf(fileID,"    --X-- --Y--\n");
            fprintf(fileID,"S1: %05d %05d  [m]\n",app.s1pos(1),app.s1pos(2));
            fprintf(fileID,"S2: %05d %05d  [m]\n",app.s2pos(1),app.s2pos(2));
            fprintf(fileID,"S3: %05d %05d  [m]\n",app.s3pos(1),app.s3pos(2));
            fprintf(fileID,"S4: %05d %05d  [m]\n",app.s4pos(1),app.s4pos(2));
            fprintf(fileID,"S5: %05d %05d  [m]\n",app.s5pos(1),app.s5pos(2));
            fprintf(fileID,"K : %05d %05d  [m]\n\n\n",app.kpos(1),app.kpos(2));
            
            fprintf(fileID,"----------Dataya ait özellikler----------\n\n");
            fprintf(fileID,"%-25s: %7d      [Hz]\n",'Frekans',app.freq);
            fprintf(fileID,"%-25s: %7d     [m/s]\n",'Ses hızı',app.v);
            fprintf(fileID,"%-25s: %7d      [Hz]\n",'Örnekleme hızı',app.Fs);
            fprintf(fileID,"%-25s: %7d      [dB]\n",'SNR',app.snr);
            fprintf(fileID,"%-25s: %7d      [dk]\n",'Sinyal süresi',app.tlast);
            fprintf(fileID,"%-25s: %7d  [sample]\n",'Başlangıç anı',first);
            fprintf(fileID,"%-25s: %s\n",'Gurultu modeli',app.noisetur);
            fprintf(fileID,"%-25s: %d\n",'Zayıflama',app.zayiflat);
            
            fclose(fileID);            
            close(bar);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            buff=event.Value;
            if buff*app.Fs>(length(app.sinyal1)-app.wlen-1)
                changingValue=[seconds((length(app.sinyal1)-app.wlen)/app.Fs-1) seconds((length(app.sinyal1)-app.wlen)/app.Fs-1)];
            else
                changingValue=[seconds(buff) seconds(buff)];
            end
            app.liner_sub.XData=changingValue;
            app.liner_top.XData=app.liner_sub.XData+app.wlen/app.Fs/86401;
        end

        % Value changed function: PenceretrDropDown
        function PenceretrDropDownValueChanged(app, event)
            app.tur = app.PenceretrDropDown.Value;
            app.SliderValueChanged(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            if app.flag==0
                uialert(app.SimlasyonUIFigure,'Sinyal üretildikten sonra bazı değişkenler değiştirildi. Lütfen simülasyonu tekrarlayın.','Dikkat');
                return;
            end
            if app.Slider.Value*app.Fs>(length(app.sinyal1)-app.wlen-1)
                app.Slider.Value=(length(app.sinyal1)-app.wlen)/app.Fs-1;
            end
            value=app.Slider.Value*app.Fs+1;
            range=round([value value+app.wlen]);
            switch app.tur
                case 'Hanning'
                    window=hann(app.wlen);
                case 'Hamming'
                    window=hamming(app.wlen);
                case 'Rectangle'
                    window=rectwin(app.wlen);
                case 'Bartlett'
                    window=bartlett(app.wlen);
                case 'Blackman'
                    window=blackman(app.wlen);
                case 'Chebyshev'
                    window=chebwin(app.wlen);
                case 'Kaiser'
                    window=kaiser(app.wlen);
                case 'Taylor'
                    window=taylorwin(app.wlen);
                case 'Triangular'
                    window=triang(app.wlen);
            end
            [g1c,f1c,app.f]=getfft(app.sinyal1,window,app.wlen,app.freq,app.Fs,app.bandwidth,range);
            [g2c,f2c]=getfft(app.sinyal2,window,app.wlen,app.freq,app.Fs,app.bandwidth,range);
            [g3c,f3c]=getfft(app.sinyal3,window,app.wlen,app.freq,app.Fs,app.bandwidth,range);
            [g4c,f4c]=getfft(app.sinyal4,window,app.wlen,app.freq,app.Fs,app.bandwidth,range);
            [g5c,f5c]=getfft(app.sinyal5,window,app.wlen,app.freq,app.Fs,app.bandwidth,range);
            plot(app.s11,app.f,g1c);
            plot(app.s12,app.f,f1c);
            plot(app.s21,app.f,g2c);
            plot(app.s22,app.f,f2c);
            plot(app.s31,app.f,g3c);
            plot(app.s32,app.f,f3c);
            plot(app.s41,app.f,g4c);
            plot(app.s42,app.f,f4c);
            plot(app.s51,app.f,g5c);
            plot(app.s52,app.f,f5c);
        end

        % Button pushed function: FazFarklarButton
        function FazFarklarButtonPushed(app, event)
            if sum([isempty(app.sg1c) isempty(app.sf1c) isempty(app.sg2c) isempty(app.sf2c) isempty(app.sg3c) isempty(app.sf3c) isempty(app.sg4c) isempty(app.sf4c) isempty(app.sg5c) isempty(app.sf5c)])>0
                uialert(app.SimlasyonUIFigure,'Lütfen simülasyonu başlatarak verileri oluşturun','Sinyal bulunamadı');
                return;
            end
            fazfarki(app,1);
        end

        % Button pushed function: KaydetmeYeriButton
        function KaydetmeYeriButtonPushed(app, event)
            app.path=[uigetdir(app.path) '\(' num2str(app.kpos(1)) ',' num2str(app.kpos(2)) ')\'];
            app.Label.Text=app.path;
        end

        % Button pushed function: FazFarklarButton_2
        function FazFarklarButton_2Pushed(app, event)
            if sum([isempty(app.sg1c) isempty(app.sf1c) isempty(app.sg2c) isempty(app.sf2c) isempty(app.sg3c) isempty(app.sf3c) isempty(app.sg4c) isempty(app.sf4c) isempty(app.sg5c) isempty(app.sf5c)])>0
                uialert(app.SimlasyonUIFigure,'Lütfen simülasyonu başlatarak verileri oluşturun','Sinyal bulunamadı');
                return;
            end
            fazfarki(app,2);
        end

        % Button pushed function: FazFarklarButton_3
        function FazFarklarButton_3Pushed(app, event)
            if sum([isempty(app.sg1c) isempty(app.sf1c) isempty(app.sg2c) isempty(app.sf2c) isempty(app.sg3c) isempty(app.sf3c) isempty(app.sg4c) isempty(app.sf4c) isempty(app.sg5c) isempty(app.sf5c)])>0
                uialert(app.SimlasyonUIFigure,'Lütfen simülasyonu başlatarak verileri oluşturun','Sinyal bulunamadı');
                return;
            end
            fazfarki(app,3);
        end

        % Button pushed function: FazFarklarButton_4
        function FazFarklarButton_4Pushed(app, event)
            if sum([isempty(app.sg1c) isempty(app.sf1c) isempty(app.sg2c) isempty(app.sf2c) isempty(app.sg3c) isempty(app.sf3c) isempty(app.sg4c) isempty(app.sf4c) isempty(app.sg5c) isempty(app.sf5c)])>0
                uialert(app.SimlasyonUIFigure,'Lütfen simülasyonu başlatarak verileri oluşturun','Sinyal bulunamadı');
                return;
            end
            fazfarki(app,4);
        end

        % Button pushed function: FazFarklarButton_5
        function FazFarklarButton_5Pushed(app, event)
            if sum([isempty(app.sg1c) isempty(app.sf1c) isempty(app.sg2c) isempty(app.sf2c) isempty(app.sg3c) isempty(app.sf3c) isempty(app.sg4c) isempty(app.sf4c) isempty(app.sg5c) isempty(app.sf5c)])>0
                uialert(app.SimlasyonUIFigure,'Lütfen simülasyonu başlatarak verileri oluşturun','Sinyal bulunamadı');
                return;
            end
            fazfarki(app,5);
        end

        % Value changed function: GrltmodeliDropDown
        function GrltmodeliDropDownValueChanged(app, event)
            app.noisetur = app.GrltmodeliDropDown.Value;  
            if strcmp(app.noisetur,'Rastgele')
                app.GurultuCizButton.Enable=0;
            else
                app.GurultuCizButton.Enable=1;
            end
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            web('http://www.ssil.itu.edu.tr/','-browser');
        end

        % Button pushed function: GurultuCizButton
        function GurultuCizButtonPushed(app, event)
            bar=uiprogressdlg(app.SimlasyonUIFigure,'Title','Lütfen Bekleyin','Message','Gürültü modeli çiziliyor...');
            bar.Value=0.05;
            try
                load([app.noisetur '.mat'],'n1e','n1n','n1z');bar.Value=0.2;
                load([app.noisetur '.mat'],'n2e','n2n','n2z');bar.Value=0.35;
                load([app.noisetur '.mat'],'n3e','n3n','n3z');bar.Value=0.5;
                load([app.noisetur '.mat'],'n4e','n4n','n4z');bar.Value=0.65;
                load([app.noisetur '.mat'],'n5e','n5n','n5z');bar.Value=0.8;
            catch
                close(bar);
                uialert(app.SimlasyonUIFigure,'Gürültü modeli yüklenemedi.','Hata');
                return
            end    
            plotoutspekt(app,n1e,n1n,n1z,1);bar.Value=0.85;  
            plotoutspekt(app,n2e,n2n,n2z,2);bar.Value=0.88; 
            plotoutspekt(app,n3e,n3n,n3z,3);bar.Value=0.92; 
            plotoutspekt(app,n4e,n4n,n4z,4);bar.Value=0.96; 
            plotoutspekt(app,n5e,n5n,n5z,5);bar.Value=1;
            pause(0.5);
            drawnow;
            pause(0.5);
            close(bar);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimlasyonUIFigure = uifigure('Visible', 'off');
            app.SimlasyonUIFigure.Name = 'Simülasyon';
            app.harita = uiaxes(app.SimlasyonUIFigure);
            title(app.harita, 'Harita')
            xlabel(app.harita, 'X (m)')
            ylabel(app.harita, 'Y (m)')
            app.harita.XLim = [-2500 2500];
            app.harita.YLim = [-2500 2500];
            app.sinyaller = uiaxes(app.SimlasyonUIFigure);
            title(app.sinyaller, 'Sinyaller')
            xlabel(app.sinyaller, 'Zaman')
            app.s11 = uiaxes(app.SimlasyonUIFigure);
            title(app.s11, 'S1')
            xlabel(app.s11, '')
            ylabel(app.s11, 'Genlik')
            app.s11.XTickLabel = '';
            app.s21 = uiaxes(app.SimlasyonUIFigure);
            title(app.s21, 'S2')
            xlabel(app.s21, '')
            app.s21.XTickLabel = '';
            app.s31 = uiaxes(app.SimlasyonUIFigure);
            title(app.s31, 'S3')
            xlabel(app.s31, '')
            app.s31.XTickLabel = '';
            app.s41 = uiaxes(app.SimlasyonUIFigure);
            title(app.s41, 'S4')
            xlabel(app.s41, '')
            app.s41.XTickLabel = '';
            app.s51 = uiaxes(app.SimlasyonUIFigure);
            title(app.s51, 'S5')
            xlabel(app.s51, '')
            app.s51.XTickLabel = '';
            app.GirdilerPanel = uipanel(app.SimlasyonUIFigure);
            app.GirdilerPanel.Title = 'Girdiler';
            app.KonumlarButton = uibutton(app.GirdilerPanel, 'push');
            app.KonumlarButton.ButtonPushedFcn = createCallbackFcn(app, @KonumlarButtonPushed, true);
            app.KonumlarButton.Text = 'Konumlar';
            app.FrekansHzLabel = uilabel(app.GirdilerPanel);
            app.FrekansHzLabel.Text = 'Frekans (Hz):';
            app.FrekansHzEditField = uieditfield(app.GirdilerPanel, 'numeric');
            app.FrekansHzEditField.Limits = [0 Inf];
            app.FrekansHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrekansHzEditFieldValueChanged, true);
            app.FrekansHzEditField.Tooltip = {'Üretilecek sinyalin frekansı'};
            app.FrekansHzEditField.Value = 50;
            app.SesHzmsLabel = uilabel(app.GirdilerPanel);
            app.SesHzmsLabel.Text = 'Ses Hızı (m/s):';
            app.SesHiziEditField = uieditfield(app.GirdilerPanel, 'numeric');
            app.SesHiziEditField.Limits = [0 Inf];
            app.SesHiziEditField.ValueChangedFcn = createCallbackFcn(app, @SesHiziEditFieldValueChanged, true);
            app.SesHiziEditField.Value = 2000;
            app.rneklemeHzLabel = uilabel(app.GirdilerPanel);
            app.rneklemeHzLabel.Text = 'Örnekleme Hızı:';
            app.OrneklemeHiziEditField = uieditfield(app.GirdilerPanel, 'numeric');
            app.OrneklemeHiziEditField.Limits = [0 Inf];
            app.OrneklemeHiziEditField.RoundFractionalValues = 'on';
            app.OrneklemeHiziEditField.ValueDisplayFormat = '%.0f';
            app.OrneklemeHiziEditField.ValueChangedFcn = createCallbackFcn(app, @OrneklemeHiziEditFieldValueChanged, true);
            app.OrneklemeHiziEditField.Tooltip = {'Sinyal üretiminde kullanılacak örnekleme hızı.'};
            app.OrneklemeHiziEditField.Value = 200;
            app.BantGeniliiHzLabel = uilabel(app.GirdilerPanel);
            app.BantGeniliiHzLabel.Text = 'Bant Genişliği (Hz):';
            app.BantGenislgiEditField = uieditfield(app.GirdilerPanel, 'numeric');
            app.BantGenislgiEditField.Limits = [0 Inf];
            app.BantGenislgiEditField.ValueChangedFcn = createCallbackFcn(app, @BantGenislgiEditFieldValueChanged, true);
            app.BantGenislgiEditField.Tooltip = {'FFT algoritmasının çalışacağı bant genişliği (Merkez frekans üretilen frekansa eşittir.)'};
            app.BantGenislgiEditField.Value = 1;
            app.SNRdBLabel = uilabel(app.GirdilerPanel);
            app.SNRdBLabel.Text = 'SNR (dB):';
            app.SNRdBEditField = uieditfield(app.GirdilerPanel, 'numeric');
            app.SNRdBEditField.ValueChangedFcn = createCallbackFcn(app, @SNRdBEditFieldValueChanged, true);
            app.SNRdBEditField.Value = 1000000;
            app.SpektrogramButton = uibutton(app.GirdilerPanel, 'push');
            app.SpektrogramButton.ButtonPushedFcn = createCallbackFcn(app, @SpektrogramButtonPushed, true);
            app.SpektrogramButton.Text = 'Spektrogram';
            app.SredkLabel = uilabel(app.GirdilerPanel);
            app.SredkLabel.Text = 'Süre (dk):';
            app.SuredkEditField = uieditfield(app.GirdilerPanel, 'numeric');
            app.SuredkEditField.Limits = [0 Inf];
            app.SuredkEditField.ValueChangedFcn = createCallbackFcn(app, @SuredkEditFieldValueChanged, true);
            app.SuredkEditField.Tooltip = {'Üretilecek sinyalin dakika cinsinden uzunluğu'};
            app.SuredkEditField.Value = 1;
            app.BaslatButton = uibutton(app.GirdilerPanel, 'push');
            app.BaslatButton.ButtonPushedFcn = createCallbackFcn(app, @BaslatButtonPushed, true);
            app.BaslatButton.Tooltip = {'Simülasyonu başlatarak verileri oluşturur.'};
            app.BaslatButton.Text = 'Başlat';
            app.ZayflamaymodelleCheckBox = uicheckbox(app.GirdilerPanel);
            app.ZayflamaymodelleCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZayflamaymodelleCheckBoxValueChanged, true);
            app.ZayflamaymodelleCheckBox.Tooltip = {'Kaynak noktasına olan uzaklığa göre sinyali zayıflatır.'};
            app.ZayflamaymodelleCheckBox.Text = 'Zayıflamayı modelle';
            app.DaaktarButton = uibutton(app.GirdilerPanel, 'push');
            app.DaaktarButton.ButtonPushedFcn = createCallbackFcn(app, @DaaktarButtonPushed, true);
            app.DaaktarButton.Tooltip = {'Üretilen sinyali gürültü modeli ile birleştirerek dışarı aktarır.'};
            app.DaaktarButton.Text = 'Dışa aktar';
            app.PencereuzunluuLabel = uilabel(app.GirdilerPanel);
            app.PencereuzunluuLabel.Text = 'Pencere uzunluğu:';
            app.PencereuzunluguEditField = uieditfield(app.GirdilerPanel, 'numeric');
            app.PencereuzunluguEditField.Limits = [0 Inf];
            app.PencereuzunluguEditField.RoundFractionalValues = 'on';
            app.PencereuzunluguEditField.ValueDisplayFormat = '%.0f';
            app.PencereuzunluguEditField.ValueChangedFcn = createCallbackFcn(app, @PencereuzunluguEditFieldValueChanged, true);
            app.PencereuzunluguEditField.Tooltip = {'Aşağıdaki grafiklerdeki frekans bölgesi gösteriminde kullanılacak pencere uzunluğu'};
            app.PencereuzunluguEditField.Value = 8192;
            app.OverlapLabel = uilabel(app.GirdilerPanel);
            app.OverlapLabel.Text = 'Overlap:';
            app.OverlapEditField = uieditfield(app.GirdilerPanel, 'numeric');
            app.OverlapEditField.Limits = [0 Inf];
            app.OverlapEditField.RoundFractionalValues = 'on';
            app.OverlapEditField.ValueDisplayFormat = '%.0f';
            app.OverlapEditField.ValueChangedFcn = createCallbackFcn(app, @OverlapEditFieldValueChanged, true);
            app.OverlapEditField.Tooltip = {'Aşağıdaki grafiklerdeki frekans bölgesi gösteriminde kullanılacak overlap miktarı'};
            app.OverlapEditField.Value = 1024;
            app.AyrlmcheckboxCheckBox = uicheckbox(app.GirdilerPanel);
            app.AyrlmcheckboxCheckBox.Enable = 'off';
            app.AyrlmcheckboxCheckBox.Visible = 'off';
            app.AyrlmcheckboxCheckBox.Tooltip = {'Geçici olarak devre dışıdır.'};
            app.AyrlmcheckboxCheckBox.Text = 'Ayrılmış check box';
            app.FazFarklarButton = uibutton(app.SimlasyonUIFigure, 'push');
            app.FazFarklarButton.ButtonPushedFcn = createCallbackFcn(app, @FazFarklarButtonPushed, true);
            app.FazFarklarButton.Text = 'Faz Farkları';
            app.FazFarklarButton_2 = uibutton(app.SimlasyonUIFigure, 'push');
            app.FazFarklarButton_2.ButtonPushedFcn = createCallbackFcn(app, @FazFarklarButton_2Pushed, true);
            app.FazFarklarButton_2.Text = 'Faz Farkları';
            app.FazFarklarButton_3 = uibutton(app.SimlasyonUIFigure, 'push');
            app.FazFarklarButton_3.ButtonPushedFcn = createCallbackFcn(app, @FazFarklarButton_3Pushed, true);
            app.FazFarklarButton_3.Text = 'Faz Farkları';
            app.FazFarklarButton_4 = uibutton(app.SimlasyonUIFigure, 'push');
            app.FazFarklarButton_4.ButtonPushedFcn = createCallbackFcn(app, @FazFarklarButton_4Pushed, true);
            app.FazFarklarButton_4.Text = 'Faz Farkları';
            app.FazFarklarButton_5 = uibutton(app.SimlasyonUIFigure, 'push');
            app.FazFarklarButton_5.ButtonPushedFcn = createCallbackFcn(app, @FazFarklarButton_5Pushed, true);
            app.FazFarklarButton_5.Text = 'Faz Farkları';
            app.Image = uiimage(app.SimlasyonUIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.Tooltip = {'Laboratuvarın sitesine erişmek için tıklayın'};
            app.Image.ImageSource = 'labamblem.PNG';
            app.s12 = uiaxes(app.SimlasyonUIFigure);
            title(app.s12, '')
            xlabel(app.s12, 'Frekans (Hz)')
            ylabel(app.s12, 'Faz')
            app.s22 = uiaxes(app.SimlasyonUIFigure);
            title(app.s22, '')
            xlabel(app.s22, 'Frekans (Hz)')
            ylabel(app.s22, '')
            app.s32 = uiaxes(app.SimlasyonUIFigure);
            title(app.s32, '')
            xlabel(app.s32, 'Frekans (Hz)')
            ylabel(app.s32, '')
            app.s42 = uiaxes(app.SimlasyonUIFigure);
            title(app.s42, '')
            xlabel(app.s42, 'Frekans (Hz)')
            ylabel(app.s42, '')
            app.s52 = uiaxes(app.SimlasyonUIFigure);
            title(app.s52, '')
            xlabel(app.s52, 'Frekans (Hz)')
            ylabel(app.s52, '')
            app.Slider = uislider(app.SimlasyonUIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.Slider.Enable = 'off';
            app.Panel = uipanel(app.SimlasyonUIFigure);
            app.PenceretrDropDownLabel = uilabel(app.Panel);
            app.PenceretrDropDownLabel.Text = 'Pencere türü:';
            app.PenceretrDropDown = uidropdown(app.Panel);
            app.PenceretrDropDown.Items = {'Hanning', 'Hamming', 'Rectangle', 'Bartlett', 'Blackman', 'Chebyshev', 'Kaiser', 'Taylor', 'Triangular'};
            app.PenceretrDropDown.ValueChangedFcn = createCallbackFcn(app, @PenceretrDropDownValueChanged, true);
            app.PenceretrDropDown.Value = 'Hanning';
            app.Label = uilabel(app.Panel);
            app.Label.Tooltip = {'Dışa aktarma sırasında kullanılacak yolu gösterir.'};
            app.Label.Text = '';
            app.KaydetmeYeriButton = uibutton(app.Panel, 'push');
            app.KaydetmeYeriButton.ButtonPushedFcn = createCallbackFcn(app, @KaydetmeYeriButtonPushed, true);
            app.KaydetmeYeriButton.Tooltip = {'Dışa aktarma sırasında kullanılacak yolu gösterir.'};
            app.KaydetmeYeriButton.Text = 'Kaydetme Yeri:';
            app.GrltmodeliDropDownLabel = uilabel(app.Panel);
            app.GrltmodeliDropDownLabel.Text = 'Gürültü modeli:';
            app.GrltmodeliDropDown = uidropdown(app.Panel);
            app.GrltmodeliDropDown.Items = {'Rastgele', '23032019_08-11', '24032019_15-17', '25032019_11-14', '27032019_11-14', '28032019_19-21', '29032019_10-12', '29032019_16-17', '30032019_04-05', '31032019_07-08', '31032019_15-17'};
            app.GrltmodeliDropDown.ValueChangedFcn = createCallbackFcn(app, @GrltmodeliDropDownValueChanged, true);
            app.GrltmodeliDropDown.Value = 'Rastgele';
            app.GurultuCizButton = uibutton(app.Panel, 'push');
            app.GurultuCizButton.ButtonPushedFcn = createCallbackFcn(app, @GurultuCizButtonPushed, true);
            app.GurultuCizButton.Icon = 'Ekran Alıntısı2.PNG';
            app.GurultuCizButton.Enable = 'off';
            app.GurultuCizButton.Tooltip = {'Gürültü modelini frekans bölgesinde gösterir.'};
            app.GurultuCizButton.Text = '';
            % Show the figure after all components are created
            app.SimlasyonUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimlasyonUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimlasyonUIFigure)
        end
    end
end