classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TamamButton              matlab.ui.control.Button
        S1Label                  matlab.ui.control.Label
        S2Label                  matlab.ui.control.Label
        S3Label                  matlab.ui.control.Label
        S4Label                  matlab.ui.control.Label
        S5Label                  matlab.ui.control.Label
        KLabel                   matlab.ui.control.Label
        KaynarastgeleseCheckBox  matlab.ui.control.CheckBox
        s1x                      matlab.ui.control.NumericEditField
        s1y                      matlab.ui.control.NumericEditField
        s2x                      matlab.ui.control.NumericEditField
        s2y                      matlab.ui.control.NumericEditField
        s3x                      matlab.ui.control.NumericEditField
        s3y                      matlab.ui.control.NumericEditField
        s4x                      matlab.ui.control.NumericEditField
        s4y                      matlab.ui.control.NumericEditField
        s5x                      matlab.ui.control.NumericEditField
        s5y                      matlab.ui.control.NumericEditField
        kx                       matlab.ui.control.NumericEditField
        ky                       matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        mainapp;
        s1pos;
        s2pos;
        s3pos;
        s4pos;
        s5pos;
        kpos;
        state;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainapp=mainapp;
            app.s1pos=mainapp.s1pos;
            app.s2pos=mainapp.s2pos;
            app.s3pos=mainapp.s3pos;
            app.s4pos=mainapp.s4pos;
            app.s5pos=mainapp.s5pos;
            app.kpos=mainapp.kpos;
            app.state=mainapp.state;
            if app.state
                app.kx.Enable=0;
                app.ky.Enable=0;
                app.KLabel.Enable=0;
                app.KaynarastgeleseCheckBox.Value=1;
            end
            if sum(isnan(app.s1pos))==0
                app.s1x.Value=app.s1pos(1);
                app.s1y.Value=app.s1pos(2);
            end
            if sum(isnan(app.s2pos))==0
                app.s2x.Value=app.s2pos(1);
                app.s2y.Value=app.s2pos(2);
            end
            if sum(isnan(app.s3pos))==0
                app.s3x.Value=app.s3pos(1);
                app.s3y.Value=app.s3pos(2);
            end
            if sum(isnan(app.s4pos))==0
                app.s4x.Value=app.s4pos(1);
                app.s4y.Value=app.s4pos(2);
            end
            if sum(isnan(app.s5pos))==0
                app.s5x.Value=app.s5pos(1);
                app.s5y.Value=app.s5pos(2);
            end
            if sum(isnan(app.kpos))==0
                app.kx.Value=app.kpos(1);
                app.ky.Value=app.kpos(2);
            end
        end

        % Value changed function: KaynarastgeleseCheckBox
        function KaynarastgeleseCheckBoxValueChanged(app, event)
            app.state = app.KaynarastgeleseCheckBox.Value;
            if app.state
                app.kx.Enable=0;
                app.ky.Enable=0;
                app.KLabel.Enable=0;
            else
                app.kx.Enable=1;
                app.ky.Enable=1;
                app.KLabel.Enable=1;
            end
        end

        % Button pushed function: TamamButton
        function TamamButtonPushed(app, event)
            app.s1pos=[app.s1x.Value app.s1y.Value];
            app.s2pos=[app.s2x.Value app.s2y.Value];
            app.s3pos=[app.s3x.Value app.s3y.Value];
            app.s4pos=[app.s4x.Value app.s4y.Value];
            app.s5pos=[app.s5x.Value app.s5y.Value];
            
            center=round((app.s1pos+app.s2pos+app.s3pos+app.s4pos+app.s5pos)/5);
            app.s1pos=app.s1pos-center;
            app.s2pos=app.s2pos-center;
            app.s3pos=app.s3pos-center;
            app.s4pos=app.s4pos-center;
            app.s5pos=app.s5pos-center;
            
            if app.state
                app.kpos=randi([-2500 2500],1,2);
            else
                app.kpos=[app.kx.Value app.ky.Value];
            end
            updatekonum(app.mainapp,app);
            UIFigureCloseRequest(app,event);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TamamButton = uibutton(app.UIFigure, 'push');
            app.TamamButton.ButtonPushedFcn = createCallbackFcn(app, @TamamButtonPushed, true);
            app.TamamButton.Text = 'Tamam';
            app.S1Label = uilabel(app.UIFigure);
            app.S1Label.Text = 'S1';
            app.S2Label = uilabel(app.UIFigure);
            app.S2Label.Text = 'S2';
            app.S3Label = uilabel(app.UIFigure);
            app.S3Label.Text = 'S3';
            app.S4Label = uilabel(app.UIFigure);
            app.S4Label.Text = 'S4';
            app.S5Label = uilabel(app.UIFigure);
            app.S5Label.Text = 'S5';
            app.KLabel = uilabel(app.UIFigure);
            app.KLabel.Text = 'K';
            app.KaynarastgeleseCheckBox = uicheckbox(app.UIFigure);
            app.KaynarastgeleseCheckBox.ValueChangedFcn = createCallbackFcn(app, @KaynarastgeleseCheckBoxValueChanged, true);
            app.KaynarastgeleseCheckBox.Text = 'Kaynağı rastgele seç';
            app.s1x = uieditfield(app.UIFigure, 'numeric');
            app.s1y = uieditfield(app.UIFigure, 'numeric');
            app.s2x = uieditfield(app.UIFigure, 'numeric');
            app.s2y = uieditfield(app.UIFigure, 'numeric');
            app.s3x = uieditfield(app.UIFigure, 'numeric');
            app.s3y = uieditfield(app.UIFigure, 'numeric');
            app.s4x = uieditfield(app.UIFigure, 'numeric');
            app.s4y = uieditfield(app.UIFigure, 'numeric');
            app.s5x = uieditfield(app.UIFigure, 'numeric');
            app.s5y = uieditfield(app.UIFigure, 'numeric');
            app.kx = uieditfield(app.UIFigure, 'numeric');
            app.ky = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end