classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpectrogramUIFigure       matlab.ui.Figure
        s1                        matlab.ui.control.UIAxes
        s2                        matlab.ui.control.UIAxes
        s3                        matlab.ui.control.UIAxes
        s4                        matlab.ui.control.UIAxes
        s5                        matlab.ui.control.UIAxes
        PencereuzunluuLabel       matlab.ui.control.Label
        PencereUEditField         matlab.ui.control.NumericEditField
        OverlapEditFieldLabel     matlab.ui.control.Label
        OverlapEditField          matlab.ui.control.NumericEditField
        PenceretrDropDownLabel    matlab.ui.control.Label
        PenceretrDropDown         matlab.ui.control.DropDown
        RenkharitasDropDownLabel  matlab.ui.control.Label
        RenkharitasDropDown       matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        tur='Hanning';
        harita='Parula';
        wlen=8192;
        overlap=1024;
        mainapp;
    end
    
    
    methods (Access = private)
        
        function plot_spektrogram(app)
            switch app.tur
                case 'Hanning'
                    window=hann(app.wlen);
                case 'Hamming'
                    window=hamming(app.wlen);
                case 'Rectangle'
                    window=rectwin(app.wlen);
                case 'Bartlett'
                    window=bartlett(app.wlen);
                case 'Blackman'
                    window=blackman(app.wlen);
                case 'Chebyshev'
                    window=chebwin(app.wlen);
                case 'Kaiser'
                    window=kaiser(app.wlen);
                case 'Taylor'
                    window=taylorwin(app.wlen);
                case 'Triangular'
                    window=triang(app.wlen);
            end
            app.mainapp.time;
            [S,F,T] = spectrogram(app.mainapp.sinyal1,window,app.overlap,'yaxis');
            F=F/pi*app.mainapp.Fs/2;
            imagesc(app.s1, T, F, log(1+abs(S)));
            set(app.s1,'YDir','normal');
            colormap(app.s1,app.harita);colorbar off;
            axis(app.s1,'tight');

            [S,F,T] = spectrogram(app.mainapp.sinyal2,window,app.overlap,'yaxis');
            F=F/pi*app.mainapp.Fs/2;
            imagesc(app.s2, T, F, log(1+abs(S)));
            set(app.s2,'YDir','normal');
            colormap(app.s2,app.harita);colorbar off;
            axis(app.s2,'tight');

            [S,F,T] = spectrogram(app.mainapp.sinyal3,window,app.overlap,'yaxis');
            F=F/pi*app.mainapp.Fs/2;
            imagesc(app.s3, T, F, log(1+abs(S)));
            set(app.s3,'YDir','normal');
            colormap(app.s3,app.harita);colorbar off;          
            axis(app.s3,'tight');

            [S,F,T] = spectrogram(app.mainapp.sinyal4,window,app.overlap,'yaxis');
            F=F/pi*app.mainapp.Fs/2;
            imagesc(app.s4, T, F, log(1+abs(S)));
            set(app.s4,'YDir','normal');
            colormap(app.s4,app.harita);colorbar off;  
            axis(app.s4,'tight');
            
            [S,F,T] = spectrogram(app.mainapp.sinyal5,window,app.overlap,'yaxis');
            F=F/pi*app.mainapp.Fs/2;
            imagesc(app.s5, T, F, log(1+abs(S)));
            set(app.s5,'YDir','normal');
            colormap(app.s5,app.harita);colorbar off;
            axis(app.s5,'tight');
            
            app.s5.XTick=app.s5.XTick(1:end-1);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            buffer=mainapp.fspektrogram;
            t=whos('buffer');t=t.class;
            if strcmp(t,'struct')
                % Değişkenleri al
                app.tur=mainapp.fspektrogram.tur;
                app.harita=mainapp.fspektrogram.harita;
                app.wlen=mainapp.fspektrogram.wlen;
                app.overlap=mainapp.fspektrogram.overlap;
                % Kutu değerleri değiştir
                app.PencereUEditField.Value=app.wlen;
                app.OverlapEditField.Value=app.overlap;
                app.PenceretrDropDown.Value=app.tur;
                app.RenkharitasDropDown.Value=app.harita;
                drawnow;
            end
            app.mainapp=mainapp;
            plot_spektrogram(app);
        end

        % Value changed function: PenceretrDropDown
        function PenceretrDropDownValueChanged(app, event)
            app.tur = app.PenceretrDropDown.Value;
            plot_spektrogram(app);
        end

        % Value changed function: PencereUEditField
        function PencereUEditFieldValueChanged(app, event)
            if app.PencereUEditField.Value <= app.overlap
                app.OverlapEditField.set('Value',app.PencereUEditField.Value/2);
                OverlapEditFieldValueChanged(app,event);
            end
            app.wlen = app.PencereUEditField.Value;
            plot_spektrogram(app);
        end

        % Value changed function: OverlapEditField
        function OverlapEditFieldValueChanged(app, event)
            pause(0.5);
            if app.OverlapEditField.Value>=app.wlen
                app.OverlapEditField.set('Value',app.wlen/2);
                OverlapEditFieldValueChanged(app,event);
            end
            app.overlap = app.OverlapEditField.Value;
            plot_spektrogram(app);
        end

        % Value changed function: RenkharitasDropDown
        function RenkharitasDropDownValueChanged(app, event)
            app.harita = app.RenkharitasDropDown.Value;
            plot_spektrogram(app);
        end

        % Close request function: SpectrogramUIFigure
        function SpectrogramUIFigureCloseRequest(app, event)
            updatespekt(app.mainapp,app);
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpectrogramUIFigure = uifigure('Visible', 'off');
            app.SpectrogramUIFigure.Name = 'Spectrogram';
            app.SpectrogramUIFigure.CloseRequestFcn = createCallbackFcn(app, @SpectrogramUIFigureCloseRequest, true);
            app.s1 = uiaxes(app.SpectrogramUIFigure);
            title(app.s1, '')
            xlabel(app.s1, '')
            ylabel(app.s1, 'S1')
            app.s1.XTickLabel = '';
            app.s2 = uiaxes(app.SpectrogramUIFigure);
            title(app.s2, '')
            xlabel(app.s2, '')
            ylabel(app.s2, 'S2')
            app.s2.XTickLabel = '';
            app.s3 = uiaxes(app.SpectrogramUIFigure);
            title(app.s3, '')
            xlabel(app.s3, '')
            ylabel(app.s3, 'S3')
            app.s3.XTickLabel = '';
            app.s4 = uiaxes(app.SpectrogramUIFigure);
            title(app.s4, '')
            xlabel(app.s4, '')
            ylabel(app.s4, 'S4')
            app.s4.XTickLabel = '';
            app.s5 = uiaxes(app.SpectrogramUIFigure);
            title(app.s5, '')
            xlabel(app.s5, '')
            ylabel(app.s5, 'S5')
            app.PencereuzunluuLabel = uilabel(app.SpectrogramUIFigure);
            app.PencereuzunluuLabel.Text = 'Pencere uzunluğu:';
            app.PencereUEditField = uieditfield(app.SpectrogramUIFigure, 'numeric');
            app.PencereUEditField.Limits = [0 Inf];
            app.PencereUEditField.RoundFractionalValues = 'on';
            app.PencereUEditField.ValueDisplayFormat = '%.0f';
            app.PencereUEditField.ValueChangedFcn = createCallbackFcn(app, @PencereUEditFieldValueChanged, true);
            app.PencereUEditField.Value = 8192;
            app.OverlapEditFieldLabel = uilabel(app.SpectrogramUIFigure);
            app.OverlapEditFieldLabel.Text = 'Overlap:';
            app.OverlapEditField = uieditfield(app.SpectrogramUIFigure, 'numeric');
            app.OverlapEditField.Limits = [0 Inf];
            app.OverlapEditField.RoundFractionalValues = 'on';
            app.OverlapEditField.ValueDisplayFormat = '%.0f';
            app.OverlapEditField.ValueChangedFcn = createCallbackFcn(app, @OverlapEditFieldValueChanged, true);
            app.OverlapEditField.Value = 1024;
            app.PenceretrDropDownLabel = uilabel(app.SpectrogramUIFigure);
            app.PenceretrDropDownLabel.Text = 'Pencere türü:';
            app.PenceretrDropDown = uidropdown(app.SpectrogramUIFigure);
            app.PenceretrDropDown.Items = {'Hanning', 'Hamming', 'Rectangle', 'Bartlett', 'Blackman', 'Chebyshev', 'Kaiser', 'Taylor', 'Triangular'};
            app.PenceretrDropDown.ValueChangedFcn = createCallbackFcn(app, @PenceretrDropDownValueChanged, true);
            app.PenceretrDropDown.Value = 'Hanning';
            app.RenkharitasDropDownLabel = uilabel(app.SpectrogramUIFigure);
            app.RenkharitasDropDownLabel.Text = 'Renk haritası:';
            app.RenkharitasDropDown = uidropdown(app.SpectrogramUIFigure);
            app.RenkharitasDropDown.Items = {'Parula', 'Jet', 'HSV', 'Hot', 'Cool', 'Spring', 'Summer', 'Autumn', 'Winter', 'Gray', 'Bone', 'Copper', 'Pink', 'Lines', 'Colorcube'};
            app.RenkharitasDropDown.ValueChangedFcn = createCallbackFcn(app, @RenkharitasDropDownValueChanged, true);
            app.RenkharitasDropDown.Value = 'Parula';
            % Show the figure after all components are created
            app.SpectrogramUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpectrogramUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpectrogramUIFigure)
        end
    end
end